classdef SHARapov < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        UIAxes     matlab.ui.control.UIAxes
        Button     matlab.ui.control.Button
        Label      matlab.ui.control.Label
        Label_2    matlab.ui.control.Label
        EditField  matlab.ui.control.EditField
    end

    
    properties (Access = private)
        dir = 0;
        score = 0;
        best_result = 0;
        snakePlot = [];
        foodPlot = [];
    end
    

    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Ожидание нажатия кнопки "OK"
            uiwait(msgbox(['Лучший результат: ' num2str(app.best_result)], 'Лучший результат'));
            
            % Инициализация параметров игры при нажатии кнопки
            dim = 20;   % Размер игрового поля
            app.score = 0;  % Сброс счета
            app.dir = 0;    % Сброс направления движения
            
            % Очистка области для рисования графика и создание свойств для хранения объектов
            cla(app.UIAxes);
            app.snakePlot = [];
            app.foodPlot = [];
            
            % Инициализация начальных координат змейки и еды
            v = 0.9;    % Интервал времени между шагами змейки
            f = [0 0];  % Координаты еды
            f(1) = round(rand * dim);
            f(2) = round(rand * dim);
            
            s = zeros(100, 2);   % Координаты змейки
            s(1, 1) = round(dim / 2);
            s(1, 2) = round(dim / 2);
            s(2, 1) = 5;
            s(2, 2) = 6;
            
            % Отрисовка змейки и еды
            app.snakePlot = plot(app.UIAxes, s([1 2], 1), s([1 2], 2), 'Marker', '.', 'MarkerSize', 20, 'LineStyle', '-', 'LineWidth', 10, 'Color', 'Green');
            hold(app.UIAxes, 'on');
            app.foodPlot = plot(app.UIAxes, f(1, 1), f(1, 2), 'Marker', '.', 'MarkerSize', 30, 'MarkerFaceColor', 'r');
            
            xlim(app.UIAxes, [0 20]);
            ylim(app.UIAxes, [0 20]);
            
            while(1)
                s_old = s;
                
                % Обновление координат головы змейки в зависимости от направления
                if(app.dir == 0)
                    s(1, 1) = s_old(1, 1) - 1;
                    s(1, 2) = s_old(1, 2);
                elseif(app.dir == 1)
                    s(1, 1) = s_old(1, 1);
                    s(1, 2) = s_old(1, 2) + 1;
                elseif(app.dir == 2)
                    s(1, 1) = s_old(1, 1) + 1;
                    s(1, 2) = s_old(1, 2);
                elseif(app.dir == 3)
                    s(1, 1) = s_old(1, 1);
                    s(1, 2) = s_old(1, 2) - 1;
                end
                
                % Обновление координат тела змейки
                for i = 2:1:length(nonzeros(s(:, 1)))
                    s(i, 1) = s_old(i - 1, 1);
                    s(i, 2) = s_old(i - 1, 2);
                end
                
                % Проверка на выход за границы игрового поля
                if(s(1, 1) > dim || s(1, 1) < 0 || s(1, 2) > dim || s(1, 2) < 0)
                    msgbox(strcat('Вышел за пределы, ну игра закончилась чё. Результат: ', int2str(app.score)), 'End');
                    break;
                end
                
                % Проверка на столкновение с собственным телом
                if(~isempty(find(ismember(s(1, :), s(2:end, :), 'rows'), 1)))
                    msgbox(strcat('Вышел за пределы, ну игра закончилась чё. Результат: ', int2str(app.score)), 'End');
                    break;
                end
                
                % Обработка поедания еды
                if(s(1, [1 2]) == f)
                    s(length(nonzeros(s(:, 1))) + 1, 1) = s_old(length(nonzeros(s_old(:, 1))), 1);
                    s(length(nonzeros(s(:, 2))) + 1, 2) = s_old(length(nonzeros(s_old(:, 2))), 2);
                    f(1) = round(rand * dim);
                    f(2) = round(rand * dim);
                    app.score = app.score + 1;
                    app.best_result = app.score;
                    v = v - 0.02;
                end
                
                % Обновление отображения на графике
                app.EditField.Value = int2str(app.score);
                
                % Проверка существования объектов
                if ishandle(app.snakePlot) && ishandle(app.foodPlot) && isvalid(app.snakePlot) && isvalid(app.foodPlot)
                    set(app.snakePlot, 'XData', nonzeros(s(:, 1)), 'YData', nonzeros(s(:, 2)));
                    set(app.foodPlot, 'XData', nonzeros(f(:, 1)), 'YData', nonzeros(f(:, 2)));
                else
                    break; % Прекратить цикл, если объекты удалены
                end
                
                pause(0.25);
            end
            
            % Очистка свойств для хранения объектов
            app.snakePlot = [];
            app.foodPlot = [];
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            
            if(strcmp(key, 'leftarrow') && app.dir~=2)
                app.dir = 0;
            end
            if(strcmp(key, 'rightarrow') && app.dir~=0)
                app.dir = 2;
            end
            if(strcmp(key, 'uparrow') && app.dir~=3)
                app.dir = 1;
            end
            if(strcmp(key, 'downarrow') && app.dir~=1)
                app.dir = 3;
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Начало';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'ЗМЕЙКА матлаб';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '🕶';
            app.EditField = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = SHARapov

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end